classdef Olympys_Trackingversion3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadImageButton                 matlab.ui.control.Button
        Slide_Name_X_YLabel             matlab.ui.control.Label
        LiveFeedButton                  matlab.ui.control.StateButton
        TakeSnapButton                  matlab.ui.control.Button
        MicroscopeResultionButtonGroup  matlab.ui.container.ButtonGroup
        xButton                         matlab.ui.control.RadioButton
        xButton_2                       matlab.ui.control.RadioButton
        xButton_3                       matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        TrackRegionButton               matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        MultiScaleDataCollectionAppOlympusMicroscopeLabel  matlab.ui.control.Label
        EnterImageNameEditFieldLabel    matlab.ui.control.Label
        EnterImageNameEditField         matlab.ui.control.EditField
        Label_4                         matlab.ui.control.Label
        ImageNumberLabel                matlab.ui.control.Label
        LoadAllImagesButton             matlab.ui.control.Button
        ImageNoEditFieldLabel           matlab.ui.control.Label
        ImageNoEditField                matlab.ui.control.NumericEditField
        FeaturesEditFieldLabel          matlab.ui.control.Label
        FeaturesEditField               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Image_name
        full_image
        Image_path
        Image_100x
        T_Image_100x
        Image_400x 
        T_Image_400x
        Image_1000x
        T_Image_1000x 
        url
        url1
        live_feed
        x_100
        x_400
        stop
        done=0
        snap=0;
        features
        fbox
        features_2
        features1
        features2
        counter_100x=0;
        counter_400x=0;
        counter_1000x=0;
        state_value;
        o_save_path;
        track=0;% Description
        filename;
        A={};
        T_Area=0;
        Area=0;
        tbox=[0 0 0 0];
        folder;
    end
    
    methods (Access = private)
        function tform=get_transfrom(app,features1,features2)
                 
                 LOV=matchFeatures(features1{3},features2{3},'MatchThreshold',10,...
                 'MaxRatio',0.8 ,'Unique',true);
                MATCHEDPOINTS1=features1{4}(LOV(:,1));
                MATCHEDPOINTS2=features2{4}(LOV(:,2));
                [tform,inlierpts1,inlierpts2,x] = estimateGeometricTransform(MATCHEDPOINTS1,MATCHEDPOINTS2,...
                    'affine','Confidence',99.9,'MaxNumTrials',5000, 'MaxDistance',0.5);
        
        end
        
        function tbox=getbox(app,tform,rect);
                 newBoxPolygon = transformPointsForward(tform, rect);
                 tbox=[newBoxPolygon(1,1) newBoxPolygon(1,2)...
                     newBoxPolygon(2,1)-newBoxPolygon(1,1)...
                     newBoxPolygon(2,2)-newBoxPolygon(1,2)];
        end
        
        
        function features = ref_features(app,I2,sz,xbox,m_th)
                features={};    
                features{1} = I2;
                Ix=rgb2gray(I2);
                Ix = imresize(Ix,sz);
                features{2}=detectSURFFeatures(Ix,'MetricThreshold',m_th,...
                    'NumOctaves',3, 'NumScaleLevels',4,'ROI',xbox);
                [features{3},features{4}]=extractFeatures(Ix,features{2});
        end
        
        function rgb = remove_black(app,img)
                 Ib=im2bw(img,0.2);
                 stats = regionprops(Ib, 'BoundingBox', 'Area');
                 [val idx]= max(vertcat(stats.Area));
                 rgb=stats(idx).BoundingBox;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                app.url = 'http://192.168.43.1:8080/shot.jpg';
                app.url1 = 'http://192.168.43.1:8080/photoaf.jpg';
                app.x_400= [284.5,982.5,342,332];%[302,861.5, 332, 325];
                app.x_100=[412.5,1054.5,85,82];%[439.5, 964.5, 84, 82];
                warning('off')
                mkdir('./China_Data/Full_Images');
                mkdir('./China_Data/Images');
                app.LoadAllImagesButton.BackgroundColor = [0    0    0.96];
                app.FeaturesEditField.Value=1000;
                
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            app.track=app.ImageNoEditField.Value;
            app.Label_4.Text=num2str(app.ImageNoEditField.Value);
%             disp(app.EnterImageNameEditField.Value)
            if app.xButton.Value==1 %100x
                app.Image_name=app.folder(app.track).name; % get the Image Name
                app.Slide_Name_X_YLabel.Text=app.Image_name; % print Image Name
                app.Image_1000x = imrotate(imread(strcat(app.Image_path,app.Image_name)),180); %load microscope camera (MC) Images
                app.Image_1000x=imresize(app.Image_1000x,0.18); %resize Image by 0.18 factor
                app.fbox = [1 1 size(app.Image_1000x,2) size(app.Image_1000x,1)]; % region on interest to calcale features
                app.features = ref_features(app,app.Image_1000x,1,app.fbox,app.FeaturesEditField.Value); %compute the features of MC Image
                imshow(app.Image_1000x,'Parent',app.UIAxes3) %plot MC Image
                cx = imread(strcat(app.Image_path,app.Image_name(1:end-3),'jpg'));  % load x coorindate Image
                cy = imread(strcat(app.Image_path,app.Image_name(1:end-3),'jpeg')); % load y coorindate Image
                imshow(cx,'Parent',app.UIAxes) %plot x-cooradinate Image
                imshow(cy,'Parent',app.UIAxes2) %plot y-cooradinate Image
                app.TrackRegionButton.BackgroundColor=[0 0 0.960]; % button color change
                app.LoadImageButton.BackgroundColor=[0.960 0.960 0.96]; % button color change
            elseif app.xButton_2.Value==1
                app.Image_name=app.folder(app.track).name 
                app.Image_400x = imrotate(imread(strcat(app.Image_path,app.Image_name)),180); %load microscope camera (MC) Images
                app.Image_400x=imresize(app.Image_400x,0.36); % resize Image by 0.36 factor
                imshow(app.Image_400x,'Parent',app.UIAxes) % plot MC Image
                app.fbox = [1 1 size(app.Image_400x,2) size(app.Image_400x,1)]; %resion of interest 
                app.features = ref_features(app,app.Image_400x,1,app.fbox,app.FeaturesEditField.Value); % compute the features of MC Image
                app.TrackRegionButton.BackgroundColor=[0 0 0.960]; % button color change
                app.LoadImageButton.BackgroundColor=[0.960 0.960 0.960]; % button color change
            else   
                app.Image_name=app.folder(app.track).name; % get the Image Name
                app.Image_1000x = imrotate(imread(strcat(app.Image_path,app.Image_name)),180); %load microscope camera (MC) Images
                imshow(app.Image_1000x,'Parent',app.UIAxes) % plot Image
                app.TakeSnapButton.BackgroundColor=[0 0 0.960];
                app.LoadImageButton.BackgroundColor=[0.960 0.960 0.960];

            end
        end

        % Value changed function: LiveFeedButton
        function LiveFeedButtonValueChanged(app, event)
            app.state_value = app.LiveFeedButton.Value;

            if app.LiveFeedButton.Value==1;
                app.LiveFeedButton.Text='Stop Feed';
                
                while (app.state_value==1)

                        pause(1)
                       app.state_value=app.LiveFeedButton.Value;
                    if app.xButton.Value==1; %100x
                        app.live_feed=imread(app.url);
                        xbox1 = remove_black(app,app.live_feed);
                        app.features2 = ref_features(app,app.live_feed,1,xbox1,5*app.FeaturesEditField.Value);
                        tform=get_transfrom(app,app.features1,app.features2);
                        J=app.tbox;
                        rect1=[J(1),J(2);J(3)+J(1),J(4)+J(2)];
                        app.tbox=getbox(app,tform,rect1);
                        app.live_feed=insertShape(app.live_feed,'Rectangle',app.tbox,'LineWidth',5,'color','blue');
                        app.live_feed=insertShape(app.live_feed,'Rectangle',app.x_100,'LineWidth',5,'color','green');
                        imshow(app.live_feed,'Parent',app.UIAxes2)
                        app.features1=app.features2;
                          
                        
                    elseif app.xButton_2.Value==1 %400x
                        app.live_feed=imread(app.url);
                        xbox1 = remove_black(app,app.live_feed);
                        app.features2 = ref_features(app,app.live_feed,1,xbox1,app.FeaturesEditField.Value);
                        tform=get_transfrom(app,app.features1,app.features2);
                        J=app.tbox;
                        rect1=[J(1),J(2);J(3)+J(1),J(4)+J(2)];
                        app.tbox=getbox(app,tform,rect1);
                        app.live_feed=insertShape(app.live_feed,'Rectangle',app.tbox,'LineWidth',5,'color','blue');
                        app.live_feed=insertShape(app.live_feed,'Rectangle',app.x_400,'LineWidth',5,'color','green');
                        imshow(app.live_feed,'Parent',app.UIAxes2)
                        app.features1=app.features2;
                    
                    else
                            app.live_feed=imread(app.url);
                            imshow(app.live_feed,'Parent',app.UIAxes2)
                            app.state_value=0;
                            app.LiveFeedButton.Value=0;
                            app.LiveFeedButton.Text='Live Feed';
                    end        
                end
                
            else
                app.LiveFeedButton.Text='Live Feed';
                app.LiveFeedButton.BackgroundColor=[0.9600   0.9600 0.9600];
                app.TakeSnapButton.BackgroundColor=[0   0   0.96];
            end
            
        end

        % Button pushed function: TrackRegionButton
        function TrackRegionButtonPushed(app, event)
 
                if app.xButton.Value==1; %100x
                   im=imrotate(imread(app.url1),270);
                   im = imresize(im,0.5);
                   xbox1 = remove_black(app,im);
                   app.features1 = ref_features(app,im,1,xbox1,app.FeaturesEditField.Value);
                   tform=get_transfrom(app,app.features,app.features1);
                   rect=[1,1;1+size(app.features{1},2),1+size(app.features{1},1)];
                   app.tbox=getbox(app,tform,rect);
                   im2=insertShape(im,'Rectangle',app.tbox,'LineWidth',20,'color','blue');
                   
                   imshow(im2,'Parent',app.UIAxes2)
                   imshow(app.features{1},'Parent',app.UIAxes)
                   imshow(app.features{1},'Parent',app.UIAxes3)
                   imshow(imcrop(im,app.tbox),'Parent',app.UIAxes4)
                   pause(1);
                   app.features1 = ref_features(app,im,1,xbox1,2*app.FeaturesEditField.Value);
                   app.live_feed=imread(app.url);
                   xbox2 = remove_black(app,app.live_feed);
                   app.features2 = ref_features(app,app.live_feed,1,xbox2,2*app.FeaturesEditField.Value);
                   tform=get_transfrom(app,app.features1,app.features2);
                   J=app.tbox;
                   rect1=[J(1),J(2);J(3)+J(1),J(4)+J(2)];
                   app.tbox=getbox(app,tform,rect1);
                   app.live_feed=insertShape(app.live_feed,'Rectangle',app.tbox,'LineWidth',5,'color','blue');
                   app.live_feed=insertShape(app.live_feed,'Rectangle',app.x_100,'LineWidth',5,'color','green');
                   imshow(app.live_feed,'Parent',app.UIAxes2)
                   app.features1=app.features2;
                   app.LiveFeedButton.BackgroundColor=[0 0 0.96];
                   app.TrackRegionButton.BackgroundColor=[0.960 0.9600 0.960];
                        
               elseif app.xButton_2.Value==1 %400x
                   im=imrotate(imread(app.url1),270);
                   im = imresize(im,0.5);
                   xbox1 = remove_black(app,im);
                   app.features1 = ref_features(app,im,1,xbox1,app.FeaturesEditField.Value);
                   tform=get_transfrom(app,app.features,app.features1);
                   rect=[1,1;1+size(app.features{1},2),1+size(app.features{1},1)];
                   app.tbox=getbox(app,tform,rect);
                   im2=insertShape(im,'Rectangle',app.tbox,'LineWidth',5,'color','blue');
                   
                   imshow(im2,'Parent',app.UIAxes2)
                   imshow(app.features{1},'Parent',app.UIAxes3)
                   imshow(imcrop(im,app.tbox),'Parent',app.UIAxes4)
                   pause(1);
                   
                   app.live_feed=imread(app.url);
                   xbox2 = remove_black(app,app.live_feed);
                   app.features2 = ref_features(app,app.live_feed,1,xbox2,app.FeaturesEditField.Value);
                   tform=get_transfrom(app,app.features1,app.features2);
                   J=app.tbox;
                   rect1=[J(1),J(2);J(3)+J(1),J(4)+J(2)];
                   app.tbox=getbox(app,tform,rect1);
                   app.live_feed=insertShape(app.live_feed,'Rectangle',app.tbox,'LineWidth',5,'color','blue');
                   app.live_feed=insertShape(app.live_feed,'Rectangle',app.x_400,'LineWidth',5,'color','green');
                   imshow(app.live_feed,'Parent',app.UIAxes2)
                   app.features1=app.features2;
                   app.TakeSnapButton.BackgroundColor=[0 0 0.96];
                   app.TrackRegionButton.BackgroundColor=[0.960 0.9600 0.960];
 
                   
                else
                       
                    im=imrotate(imread(app.url1),270);
                    imshow(im,'Parent',app.UIAxes2)

                end
                
        end

        % Button pushed function: TakeSnapButton
        function TakeSnapButtonPushed(app, event)
                    app.o_save_path = strcat('.\China_Data\Images\',app.Image_name(1:end-4),'_',app.EnterImageNameEditField.Value,'_');
                    app.o_save_path
                    app.Image_name(1:end-4)
                    
                    if app.xButton.Value == 1 %100x
                       app.full_image = imrotate(imread(app.url1),270);
                       imshow(app.full_image,'Parent',app.UIAxes);
                       xbox2 = remove_black(app,app.full_image);
                       app.features1 = ref_features(app,app.full_image,1,xbox2,app.FeaturesEditField.Value);
                       tform=get_transfrom(app,app.features,app.features1);
                       rect=[1,1;1+size(app.features{1},2),1+size(app.features{1},1)];
                       app.tbox=getbox(app,tform,rect); 
                       im=insertShape(app.full_image,'Rectangle',app.tbox,'LineWidth',20,'color','blue');
                       app.filename=strcat(app.o_save_path,'100x.tif');
                       imshow(im,'Parent',app.UIAxes2);
                       imshow(imcrop(app.full_image,app.tbox),'Parent',app.UIAxes4)
                       app.Label.Text= 'Done';
                       imwrite(app.full_image,app.filename);
                       app.xButton_2.Value=1;
                       app.A{3,1}=app.filename;
                       app.A{3,2}=app.tbox;
                       app.LoadImageButton.BackgroundColor=[0. 0 0.960];
                       app.TakeSnapButton.BackgroundColor=[0.960 0.960 0.9600];
                       
                    elseif app.xButton_2.Value == 1 %400
                       
                       app.full_image = imrotate(imread(app.url1),270);
                       imshow(app.full_image,'Parent',app.UIAxes);
                       xbox2 = remove_black(app,app.full_image);
                       app.features1 = ref_features(app,app.full_image,1,xbox2,app.FeaturesEditField.Value);
                       tform=get_transfrom(app,app.features,app.features1);
                       rect=[1,1;1+size(app.features{1},2),1+size(app.features{1},1)];
                       app.tbox=getbox(app,tform,rect); 
                       im=insertShape(app.full_image,'Rectangle',app.tbox,'LineWidth',20,'color','blue');
                       app.filename=strcat(app.o_save_path,'400x.tif');
                       imshow(im,'Parent',app.UIAxes2);
                       imshow(imcrop(app.full_image,app.tbox),'Parent',app.UIAxes4)
                       app.Label_2.Text= 'Done';
                       imwrite(app.full_image,app.filename);
                       app.xButton_3.Value=1;
                       app.A{2,1}=app.filename;
                       app.A{2,2}=app.tbox;
                       app.LoadImageButton.BackgroundColor=[0. 0 0.960];
                       app.TakeSnapButton.BackgroundColor=[0.960 0.960 0.9600];
                    else  %%100x
                       app.full_image = imrotate(imread(app.url1),270);
                       imshow(app.full_image,'Parent',app.UIAxes2);
                       app.filename=strcat(app.o_save_path,'1000x.tif');
                       app.Label_3.Text="Done";
                       imwrite(app.full_image,app.filename);
                       app.A{1,1}=app.filename;
                       app.Label.Text='Done';
                       app.Label_3.Text='Done';
                       pause(1)
                       message = sprintf('Data is Saved! Load Next Image! Then enter new file name');
                       opts = struct('WindowStyle','non-modal','Interpreter','tex');
                       errordlg(message,'Done', opts);
                       app.track=app.track+1;
                       app.ImageNoEditField.Value=app.track;
                       
                       if app.track >length(app.folder)
                           app.track=1;
                           app.ImageNoEditField.Value=app.track;
                           app.LoadAllImagesButton.BackgroundColor=[0. 0 0.960];
                           app.TakeSnapButton.BackgroundColor=[0.960 0.960 0.9600];
                           app.xButton.Value=1;
                           csv_filename=strcat(app.o_save_path,'1000x.csv');
                           writetable(cell2table(app.A),csv_filename);
                           app.EnterImageNameEditField.Value='';
                           app.A={};
                           app.counter_1000x=0;           
                           app.counter_400x=0;  
                           app.counter_100x=0;
                           
                            app.Label.Text='0';
                            app.Label_2.Text='0';
                            app.Label_3.Text='0';
                            message = sprintf('Data is Saved! Load slide!');
                            opts = struct('WindowStyle','non-modal','Interpreter','tex');
                            errordlg(message,'Done', opts);
                       else
                           csv_filename=strcat(app.o_save_path,'1000x.csv');
                            writetable(cell2table(app.A),csv_filename);
                            app.EnterImageNameEditField.Value='';
                            app.A={};
                            app.counter_1000x=0;           
                            app.counter_400x=0;  
                            app.counter_100x=0;
                           
                            app.Label.Text='0';
                            app.Label_2.Text='0';
                            app.Label_3.Text='0';
                            app.xButton.Value=1; 
                            app.LoadImageButton.BackgroundColor=[0. 0 0.960];
                            app.TakeSnapButton.BackgroundColor=[0.960 0.960 0.9600];
%                           app.UIAxes.cla;
%                           app.UIAxes2.cla;    
%                             app.UIAxes3.cla;
%                             app.UIAxes4.cla;

                       end
                    end
        end

        % Button pushed function: LoadAllImagesButton
        function LoadAllImagesButtonPushed(app, event)
                app.Image_path=uigetdir();
                app.folder= dir(fullfile(app.Image_path,"*.tif"));
                app.Image_path=strcat(app.Image_path,'\');
                app.LoadAllImagesButton.BackgroundColor=[0.9600 0.9600 0.9600];
                app.LoadImageButton.BackgroundColor=[0  0 0.9];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.Slide_Name_X_YLabel = uilabel(app.UIFigure);
            app.Slide_Name_X_YLabel.Text = '        Slide_Name_X_Y         ';
            app.LiveFeedButton = uibutton(app.UIFigure, 'state');
            app.LiveFeedButton.ValueChangedFcn = createCallbackFcn(app, @LiveFeedButtonValueChanged, true);
            app.LiveFeedButton.Text = 'Live Feed';
            app.TakeSnapButton = uibutton(app.UIFigure, 'push');
            app.TakeSnapButton.ButtonPushedFcn = createCallbackFcn(app, @TakeSnapButtonPushed, true);
            app.TakeSnapButton.Text = 'Take Snap';
            app.MicroscopeResultionButtonGroup = uibuttongroup(app.UIFigure);
            app.MicroscopeResultionButtonGroup.Title = 'Microscope Resultion';
            app.xButton = uiradiobutton(app.MicroscopeResultionButtonGroup);
            app.xButton.Text = '100x';
            app.xButton.Value = true;
            app.xButton_2 = uiradiobutton(app.MicroscopeResultionButtonGroup);
            app.xButton_2.Text = '400x';
            app.xButton_3 = uiradiobutton(app.MicroscopeResultionButtonGroup);
            app.xButton_3.Text = '1000x';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Olympus Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Live Feed')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.TrackRegionButton = uibutton(app.UIFigure, 'push');
            app.TrackRegionButton.ButtonPushedFcn = createCallbackFcn(app, @TrackRegionButtonPushed, true);
            app.TrackRegionButton.Text = 'Track Region';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Reference Image')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Detected Image')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '0';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '0';
            app.MultiScaleDataCollectionAppOlympusMicroscopeLabel = uilabel(app.UIFigure);
            app.MultiScaleDataCollectionAppOlympusMicroscopeLabel.Text = 'Multi Scale Data Collection App Olympus Microscope';
            app.EnterImageNameEditFieldLabel = uilabel(app.UIFigure);
            app.EnterImageNameEditFieldLabel.Text = 'Enter Image Name';
            app.EnterImageNameEditField = uieditfield(app.UIFigure, 'text');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '0';
            app.ImageNumberLabel = uilabel(app.UIFigure);
            app.ImageNumberLabel.Text = 'Image Number';
            app.LoadAllImagesButton = uibutton(app.UIFigure, 'push');
            app.LoadAllImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAllImagesButtonPushed, true);
            app.LoadAllImagesButton.Text = 'Load All Images';
            app.ImageNoEditFieldLabel = uilabel(app.UIFigure);
            app.ImageNoEditFieldLabel.Text = 'Image No.';
            app.ImageNoEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageNoEditField.Value = 1;
            app.FeaturesEditFieldLabel = uilabel(app.UIFigure);
            app.FeaturesEditFieldLabel.Text = 'Features';
            app.FeaturesEditField = uieditfield(app.UIFigure, 'numeric');
            app.FeaturesEditField.Value = 1000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Olympys_Trackingversion3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end